classdef PlumeTraP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlumeTraP210UIFigure           matlab.ui.Figure
        Hyperlink                      matlab.ui.control.Hyperlink
        CopyrightLabel                 matlab.ui.control.Label
        GeomCalSwitch                  matlab.ui.control.Switch
        WindCorrSwitch                 matlab.ui.control.Switch
        OutFolderPanel                 matlab.ui.container.Panel
        OutFormatDropDown              matlab.ui.control.DropDown
        OutFormatLabel                 matlab.ui.control.Label
        OutFolderBrowseButton          matlab.ui.control.Button
        OutFolderEditField             matlab.ui.control.EditField
        WindCorrectionPanel            matlab.ui.container.Panel
        footnoteLabel                  matlab.ui.control.Label
        asteriskLabel                  matlab.ui.control.Label
        omegawcNoteLabel               matlab.ui.control.Label
        WindCorrButtonGroup            matlab.ui.container.ButtonGroup
        WindEditField                  matlab.ui.control.EditField
        knownWindManualButton          matlab.ui.control.RadioButton
        timeEditField                  matlab.ui.control.EditField
        timeLabel                      matlab.ui.control.Label
        dateEditField                  matlab.ui.control.EditField
        dateLabel                      matlab.ui.control.Label
        VentHEditField                 matlab.ui.control.EditField
        VentHLabel                     matlab.ui.control.Label
        VentCooLabel                   matlab.ui.control.Label
        VentLongEditField              matlab.ui.control.EditField
        VentLongLabel                  matlab.ui.control.Label
        VentLatEditField               matlab.ui.control.EditField
        VentLatLabel                   matlab.ui.control.Label
        WindNCEditField                matlab.ui.control.EditField
        WindNCBrowseButton             matlab.ui.control.Button
        GeopotNCEditField              matlab.ui.control.EditField
        GeopotNCBrowseButton           matlab.ui.control.Button
        ERA5Button                     matlab.ui.control.RadioButton
        VentLatLongNoteLabel           matlab.ui.control.Label
        VentHNoteLabel                 matlab.ui.control.Label
        dateNoteLabel                  matlab.ui.control.Label
        timeNoteLabel                  matlab.ui.control.Label
        WindNoteLabel                  matlab.ui.control.Label
        vFOVwcEditField                matlab.ui.control.EditField
        vFOVwcLabel                    matlab.ui.control.Label
        hFOVwcEditField                matlab.ui.control.EditField
        hFOVwcLabel                    matlab.ui.control.Label
        phiwcEditField                 matlab.ui.control.EditField
        phiwcLabel                     matlab.ui.control.Label
        omegawcEditField               matlab.ui.control.EditField
        omegawcLabel                   matlab.ui.control.Label
        GeometricalCalibrationPanel    matlab.ui.container.Panel
        GeomCalLabel                   matlab.ui.control.Label
        VerticalEditFieldLabel         matlab.ui.control.Label
        GeomCalVBrowseButton           matlab.ui.control.Button
        GeomCalVEditField              matlab.ui.control.EditField
        HorizontalEditFieldLabel       matlab.ui.control.Label
        GeomCalHBrowseButton           matlab.ui.control.Button
        GeomCalHEditField              matlab.ui.control.EditField
        GeomCalButtonGroup             matlab.ui.container.ButtonGroup
        GeomFileBrowseButton           matlab.ui.control.Button
        GeomFileEditField              matlab.ui.control.EditField
        GeomCalFileButton              matlab.ui.control.RadioButton
        phiLabel                       matlab.ui.control.Label
        phiEditField                   matlab.ui.control.EditField
        vFOVEditField                  matlab.ui.control.EditField
        hFOVEditField                  matlab.ui.control.EditField
        FOVLabel                       matlab.ui.control.Label
        MaxDistanceEditField           matlab.ui.control.EditField
        MinDistanceEditField           matlab.ui.control.EditField
        GeomCalManualButton            matlab.ui.control.RadioButton
        ProcessingOptionsPanel         matlab.ui.container.Panel
        timestep                       matlab.ui.control.EditField
        CalculateParametersCheckBox    matlab.ui.control.CheckBox
        ApplyImageProcessingCheckBox   matlab.ui.control.CheckBox
        SaveVideoFramesCheckBox        matlab.ui.control.CheckBox
        TimestepbetweenframesfpsLabel  matlab.ui.control.Label
        InputPanel                     matlab.ui.container.Panel
        InFolderBrowseButton           matlab.ui.control.Button
        InFolderEditFieldLabel         matlab.ui.control.Label
        InFolderEditField              matlab.ui.control.EditField
        FormatDropDownLabel            matlab.ui.control.Label
        ImgFormatDropDown              matlab.ui.control.DropDown
        InputImgFormEditField          matlab.ui.control.EditField
        VidFormatDropDown              matlab.ui.control.DropDown
        InputVidFormEditField          matlab.ui.control.EditField
        SourceSwitch                   matlab.ui.control.Switch
        UserGuideLink                  matlab.ui.control.Hyperlink
        RunButton                      matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % close all force
            evalin('base', 'clearvars *')
            assignin('base','source','v')
            assignin('base','inFormat',sprintf('*.%s',app.VidFormatDropDown.Value))
            assignin('base','saveframes',app.SaveVideoFramesCheckBox.Value)
            assignin('base','procframes',app.ApplyImageProcessingCheckBox.Value)
            assignin('base','parameters',app.CalculateParametersCheckBox.Value)
            assignin('base','scale_fr',str2double(app.timestep.Value))
            assignin('base','gcal','i')
            assignin('base','wcor',true)
            assignin('base','par_wind',NaN)
            assignin('base','outFormat',sprintf('*.%s',app.OutFormatDropDown.Value))
        end

        % Value changed function: SourceSwitch
        function SourceSwitchValueChanged(app, event)
            if app.SourceSwitch.Value == "Videos"
                assignin('base','source','v')
                app.ImgFormatDropDown.Visible = 'off';
                app.VidFormatDropDown.Visible = 'on';
                app.SaveVideoFramesCheckBox.Enable = 'on';
                app.SaveVideoFramesCheckBox.Value = true;
                assignin('base','saveframes',app.SaveVideoFramesCheckBox.Value)
                app.InputImgFormEditField.Visible = 'off';
                if app.VidFormatDropDown.Value == "other"
                    app.InputVidFormEditField.Visible = 'on';
                    assignin('base','inFormat',sprintf('*.%s',app.InputVidFormEditField.Value))
                else
                    app.InputVidFormEditField.Visible = 'off';
                    assignin('base','inFormat',sprintf('*.%s',app.VidFormatDropDown.Value))
                end
            elseif app.SourceSwitch.Value == "Images"
                assignin('base','source','i')
                app.ImgFormatDropDown.Visible = 'on';
                app.VidFormatDropDown.Visible = 'off';
                app.SaveVideoFramesCheckBox.Enable = 'off';
                app.SaveVideoFramesCheckBox.Value = false;
                assignin('base','saveframes',app.SaveVideoFramesCheckBox.Value)
                app.InputVidFormEditField.Visible = 'off';
                if app.ImgFormatDropDown.Value == "other"
                    app.InputImgFormEditField.Visible = 'on';
                    assignin('base','inFormat',sprintf('*.%s',app.InputImgFormEditField.Value))
                else
                    app.InputImgFormEditField.Visible = 'off';
                    assignin('base','inFormat',sprintf('*.%s',app.ImgFormatDropDown.Value))
                end
            end
        end

        % Value changed function: VidFormatDropDown
        function VidFormatDropDownValueChanged(app, event)
            if app.VidFormatDropDown.Value == "other"
                app.InputVidFormEditField.Visible = 'on';
            else
                app.InputVidFormEditField.Visible = 'off';
                assignin('base','inFormat',sprintf('*.%s',app.VidFormatDropDown.Value))
            end
        end

        % Value changed function: ImgFormatDropDown
        function ImgFormatDropDownValueChanged(app, event)
            if app.ImgFormatDropDown.Value == "other"
                app.InputImgFormEditField.Visible = 'on';
            else
                app.InputImgFormEditField.Visible = 'off';
                assignin('base','inFormat',sprintf('*.%s',app.ImgFormatDropDown.Value))
            end
        end

        % Button pushed function: InFolderBrowseButton
        function InFolderBrowseButtonPushed(app, event)
            evalin('base', 'clear files')
            if app.SourceSwitch.Value == "Videos"
                if app.VidFormatDropDown.Value == "other"
                    if isempty(app.InputVidFormEditField.Value)
                        uiwait(msgbox("Insert format extension","Warning","warn"))
                        files = []; inFolder = [];
                    else
                        [files,inFolder] = uigetfile(sprintf('*.%s',app.InputVidFormEditField.Value),'Select Videos','MultiSelect','on');
                        app.PlumeTraP210UIFigure.Visible = 'off';
                        app.PlumeTraP210UIFigure.Visible = 'on';
                        assignin('base','inFormat',sprintf('*.%s',app.InputVidFormEditField.Value))
                    end
                else
                    [files,inFolder] = uigetfile(sprintf('*.%s',app.VidFormatDropDown.Value),'Select Videos','MultiSelect','on');
                    app.PlumeTraP210UIFigure.Visible = 'off';
                    app.PlumeTraP210UIFigure.Visible = 'on';
                end
            elseif app.SourceSwitch.Value == "Images"
                if app.ImgFormatDropDown.Value == "other"
                    if isempty(app.InputImgFormEditField.Value)
                        uiwait(msgbox("Insert format extension","Warning","warn"))
                        files = []; inFolder = [];
                    else
                        [files,inFolder] = uigetfile(sprintf('*.%s',app.InputImgFormEditField.Value),'Select Images','MultiSelect','on');
                        app.PlumeTraP210UIFigure.Visible = 'off';
                        app.PlumeTraP210UIFigure.Visible = 'on';
                        assignin('base','inFormat',sprintf('*.%s',app.InputImgFormEditField.Value))
                    end
                elseif app.ImgFormatDropDown.Value == "jpeg"
                    [files,inFolder] = uigetfile({'*.jpg;*.jpeg'},'Select Images','MultiSelect','on');
                    if ischar(files) == 1
                        [~,~,ext] = fileparts(fullfile(inFolder,files));
                    else
                        [~,~,ext] = fileparts(fullfile(inFolder,char(files(1))));
                    end
                    assignin('base','inFormat',sprintf('*%s',ext))
                    app.PlumeTraP210UIFigure.Visible = 'off';
                    app.PlumeTraP210UIFigure.Visible = 'on';
                elseif app.ImgFormatDropDown.Value == "tiff"
                    [files,inFolder] = uigetfile({'*.tif;*.tiff'},'Select Images','MultiSelect','on');
                    if ischar(files) == 1
                        [~,~,ext] = fileparts(fullfile(inFolder,files));
                    else
                        [~,~,ext] = fileparts(fullfile(inFolder,char(files(1))));
                    end
                    assignin('base','inFormat',sprintf('*%s',ext))
                    app.PlumeTraP210UIFigure.Visible = 'off';
                    app.PlumeTraP210UIFigure.Visible = 'on';
                else
                    [files,inFolder] = uigetfile(sprintf('*.%s',app.ImgFormatDropDown.Value),'Select Images','MultiSelect','on');
                    app.PlumeTraP210UIFigure.Visible = 'off';
                    app.PlumeTraP210UIFigure.Visible = 'on';
                end
            end
            if ~isa(files,'double') && ~isa(inFolder,'double')
                assignin('base','files',cellstr(files))
                assignin('base','inFolder',inFolder)
                if ischar(inFolder) || isstring(inFolder)
                    app.InFolderEditField.Value = inFolder;
                end
            end
        end

        % Value changed function: SaveVideoFramesCheckBox
        function SaveVideoFramesCheckBoxValueChanged(app, event)
            assignin('base','saveframes',app.SaveVideoFramesCheckBox.Value)
        end

        % Value changed function: ApplyImageProcessingCheckBox
        function ApplyImageProcessingCheckBoxValueChanged(app, event)
            assignin('base','procframes',app.ApplyImageProcessingCheckBox.Value)
        end

        % Value changed function: CalculateParametersCheckBox
        function CalculateParametersCheckBoxValueChanged(app, event)
            assignin('base','parameters',app.CalculateParametersCheckBox.Value)
            if app.CalculateParametersCheckBox.Value == false
                app.WindCorrectionPanel.Enable = 'off';
                app.GeometricalCalibrationPanel.Enable = 'off';
                app.WindCorrSwitch.Enable = 'off';
                app.GeomCalSwitch.Enable = 'off';
            elseif app.CalculateParametersCheckBox.Value == true
                app.WindCorrectionPanel.Enable = 'on';
                app.GeometricalCalibrationPanel.Enable = 'on';
                app.WindCorrSwitch.Enable = 'on';
                app.GeomCalSwitch.Enable = 'on';
            end
        end

        % Value changed function: timestep
        function timestepValueChanged(app, event)
            scale_fr = 1/str2double(app.timestep.Value);
            assignin('base','scale_fr',scale_fr)
        end

        % Value changed function: GeomCalSwitch
        function GeomCalSwitchValueChanged(app, event)
            if app.GeomCalSwitch.Value == "On"
                app.GeomCalVBrowseButton.Visible = 'off';
                app.HorizontalEditFieldLabel.Visible = 'off';
                app.GeomCalVEditField.Visible = 'off';
                app.GeomCalHBrowseButton.Visible = 'off';
                app.GeomCalHEditField.Visible = 'off';
                app.VerticalEditFieldLabel.Visible = 'off';
                app.GeomCalLabel.Visible = 'off';
                app.GeomCalVBrowseButton.Enable = 'off';
                app.HorizontalEditFieldLabel.Enable = 'off';
                app.GeomCalVEditField.Enable = 'off';
                app.GeomCalHBrowseButton.Enable = 'off';
                app.GeomCalHEditField.Enable = 'off';
                app.VerticalEditFieldLabel.Enable = 'off';
                app.GeomCalLabel.Enable = 'off';
                app.GeomCalButtonGroup.Visible = 'on';
                if app.GeomCalButtonGroup.SelectedObject.Text == "File (txt or csv)"
                    app.GeomFileBrowseButton.Enable = 'on';
                    app.GeomFileEditField.Enable = 'on';
                    app.MinDistanceEditField.Enable = 'off';
                    app.MaxDistanceEditField.Enable = 'off';
                    app.hFOVEditField.Enable = 'off';
                    app.vFOVEditField.Enable = 'off';
                    app.phiEditField.Enable = 'off';
                    assignin('base','gcal','c')
                    app.phiwcLabel.Enable = 'off';
                    app.phiwcEditField.Enable = 'off';
                    app.omegawcLabel.Enable = 'off';
                    app.omegawcEditField.Enable = 'off';
                    app.hFOVwcLabel.Enable = 'off';
                    app.hFOVwcEditField.Enable = 'off';
                    app.vFOVwcLabel.Enable = 'off';
                    app.vFOVwcEditField.Enable = 'off';
                    app.omegawcNoteLabel.Visible = 'on';
                    app.asteriskLabel.Visible = 'on';
                    app.footnoteLabel.Visible = 'on';
                    if app.WindCorrButtonGroup.SelectedObject.Text == "Wind correction from ERA5 hourly data on pressure levels"
                        app.timeLabel.Enable = 'off';
                        app.timeEditField.Enable = 'off';
                        app.dateLabel.Enable = 'off';
                        app.dateEditField.Enable = 'off';
                        app.VentHLabel.Enable = 'off';
                        app.VentHEditField.Enable = 'off';
                        app.VentLongLabel.Enable = 'off';
                        app.VentLongEditField.Enable = 'off';
                        app.VentLatLabel.Enable = 'off';
                        app.VentLatEditField.Enable = 'off';
                        app.VentCooLabel.Enable = 'off';
                    else
                        app.WindEditField.Enable = 'off';
                        assignin('base','par_wind',NaN)
                    end
                    app.timeNoteLabel.Visible = "on";
                    app.dateNoteLabel.Visible = "on";
                    app.VentHNoteLabel.Visible = "on";
                    app.VentLatLongNoteLabel.Visible = "on";
                    app.WindNoteLabel.Visible = "on";
                elseif app.GeomCalButtonGroup.SelectedObject.Text == "Min-Max distance from camera to image plane [m]:                    -"
                    app.GeomFileBrowseButton.Enable = 'off';
                    app.GeomFileEditField.Enable = 'off';
                    app.MinDistanceEditField.Enable = 'on';
                    app.MaxDistanceEditField.Enable = 'on';
                    app.hFOVEditField.Enable = 'on';
                    app.vFOVEditField.Enable = 'on';
                    app.phiEditField.Enable = 'on';
                    assignin('base','gcal','i')
                    app.phiwcLabel.Enable = 'off';
                    app.phiwcEditField.Enable = 'off';
                    app.omegawcLabel.Enable = 'on';
                    app.omegawcEditField.Enable = 'on';
                    app.hFOVwcLabel.Enable = 'off';
                    app.hFOVwcEditField.Enable = 'off';
                    app.vFOVwcLabel.Enable = 'off';
                    app.vFOVwcEditField.Enable = 'off';
                    app.omegawcNoteLabel.Visible = 'off';
                    app.asteriskLabel.Visible = 'off';
                    app.footnoteLabel.Visible = 'off';
                    if app.WindCorrButtonGroup.SelectedObject.Text == "Wind correction from ERA5 hourly data on pressure levels"
                        app.timeLabel.Enable = 'on';
                        app.timeEditField.Enable = 'on';
                        app.dateLabel.Enable = 'on';
                        app.dateEditField.Enable = 'on';
                        app.VentHLabel.Enable = 'on';
                        app.VentHEditField.Enable = 'on';
                        app.VentLongLabel.Enable = 'on';
                        app.VentLongEditField.Enable = 'on';
                        app.VentLatLabel.Enable = 'on';
                        app.VentLatEditField.Enable = 'on';
                        app.VentCooLabel.Enable = 'on';
                    else
                        app.WindEditField.Enable = 'on';
                        assignin('base','par_wind',app.WindEditField.Value)
                    end
                    app.timeNoteLabel.Visible = "off";
                    app.dateNoteLabel.Visible = "off";
                    app.VentHNoteLabel.Visible = "off";
                    app.VentLatLongNoteLabel.Visible = "off";
                    app.WindNoteLabel.Visible = "off";
                end
            elseif app.GeomCalSwitch.Value == "Off"
                app.GeomCalVBrowseButton.Visible = 'on';
                app.HorizontalEditFieldLabel.Visible = 'on';
                app.GeomCalVEditField.Visible = 'on';
                app.GeomCalHBrowseButton.Visible = 'on';
                app.GeomCalHEditField.Visible = 'on';
                app.VerticalEditFieldLabel.Visible = 'on';
                app.GeomCalLabel.Visible = 'on';
                app.GeomCalVBrowseButton.Enable = 'on';
                app.HorizontalEditFieldLabel.Enable = 'on';
                app.GeomCalVEditField.Enable = 'on';
                app.GeomCalHBrowseButton.Enable = 'on';
                app.GeomCalHEditField.Enable = 'on';
                app.VerticalEditFieldLabel.Enable = 'on';
                app.GeomCalLabel.Enable = 'on';
                app.GeomCalButtonGroup.Visible = 'off';
                app.GeomFileBrowseButton.Enable = 'off';
                app.GeomFileEditField.Enable = 'off';
                app.MinDistanceEditField.Enable = 'off';
                app.MaxDistanceEditField.Enable = 'off';
                app.hFOVEditField.Enable = 'off';
                app.vFOVEditField.Enable = 'off';
                app.phiEditField.Enable = 'off';
                assignin('base','gcal','u')
                app.phiwcLabel.Enable = 'on';
                app.phiwcEditField.Enable = 'on';
                app.omegawcLabel.Enable = 'on';
                app.omegawcEditField.Enable = 'on';
                app.hFOVwcLabel.Enable = 'on';
                app.hFOVwcEditField.Enable = 'on';
                app.vFOVwcLabel.Enable = 'on';
                app.vFOVwcEditField.Enable = 'on';
                app.omegawcNoteLabel.Visible = 'off';
                app.asteriskLabel.Visible = 'off';
                app.footnoteLabel.Visible = 'off';
                if app.WindCorrButtonGroup.SelectedObject.Text == "Wind correction from ERA5 hourly data on pressure levels"
                    app.timeLabel.Enable = 'on';
                    app.timeEditField.Enable = 'on';
                    app.dateLabel.Enable = 'on';
                    app.dateEditField.Enable = 'on';
                    app.VentHLabel.Enable = 'on';
                    app.VentHEditField.Enable = 'on';
                    app.VentLongLabel.Enable = 'on';
                    app.VentLongEditField.Enable = 'on';
                    app.VentLatLabel.Enable = 'on';
                    app.VentLatEditField.Enable = 'on';
                    app.VentCooLabel.Enable = 'on';
                else
                    app.WindEditField.Enable = 'on';
                    assignin('base','par_wind',app.WindEditField.Value)
                end
                app.timeNoteLabel.Visible = "off";
                app.dateNoteLabel.Visible = "off";
                app.VentHNoteLabel.Visible = "off";
                app.VentLatLongNoteLabel.Visible = "off";
                app.WindNoteLabel.Visible = "off";
            end
        end

        % Selection changed function: GeomCalButtonGroup
        function GeomCalButtonGroupSelectionChanged(app, event)
            if app.GeomCalButtonGroup.SelectedObject.Text == "Min-Max distance from camera to image plane [m]:                    -"
                app.GeomFileBrowseButton.Enable = 'off';
                app.GeomFileEditField.Enable = 'off';
                app.MinDistanceEditField.Enable = 'on';
                app.MaxDistanceEditField.Enable = 'on';
                app.hFOVEditField.Enable = 'on';
                app.vFOVEditField.Enable = 'on';
                app.phiEditField.Enable = 'on';
                assignin('base','gcal','i')
                app.phiwcLabel.Enable = 'off';
                app.phiwcEditField.Enable = 'off';
                app.omegawcLabel.Enable = 'on';
                app.omegawcEditField.Enable = 'on';
                app.hFOVwcLabel.Enable = 'off';
                app.hFOVwcEditField.Enable = 'off';
                app.vFOVwcLabel.Enable = 'off';
                app.vFOVwcEditField.Enable = 'off';
                app.omegawcNoteLabel.Visible = 'off';
                app.asteriskLabel.Visible = 'off';
                app.footnoteLabel.Visible = 'off';
                if app.WindCorrButtonGroup.SelectedObject.Text == "Wind correction from ERA5 hourly data on pressure levels"
                    app.timeLabel.Enable = 'on';
                    app.timeEditField.Enable = 'on';
                    app.dateLabel.Enable = 'on';
                    app.dateEditField.Enable = 'on';
                    app.VentHLabel.Enable = 'on';
                    app.VentHEditField.Enable = 'on';
                    app.VentLongLabel.Enable = 'on';
                    app.VentLongEditField.Enable = 'on';
                    app.VentLatLabel.Enable = 'on';
                    app.VentLatEditField.Enable = 'on';
                    app.VentCooLabel.Enable = 'on';
                else
                    app.WindEditField.Enable = 'on';
                    assignin('base','par_wind',app.WindEditField.Value)
                end
                app.timeNoteLabel.Visible = "off";
                app.dateNoteLabel.Visible = "off";
                app.VentHNoteLabel.Visible = "off";
                app.VentLatLongNoteLabel.Visible = "off";
                app.WindNoteLabel.Visible = "off";
            elseif app.GeomCalButtonGroup.SelectedObject.Text == "File (txt or csv)"
                app.GeomFileBrowseButton.Enable = 'on';
                app.GeomFileEditField.Enable = 'on';
                app.MinDistanceEditField.Enable = 'off';
                app.MaxDistanceEditField.Enable = 'off';
                app.hFOVEditField.Enable = 'off';
                app.vFOVEditField.Enable = 'off';
                app.phiEditField.Enable = 'off';
                assignin('base','gcal','c')
                app.phiwcLabel.Enable = 'off';
                app.phiwcEditField.Enable = 'off';
                app.omegawcLabel.Enable = 'off';
                app.omegawcEditField.Enable = 'off';
                app.hFOVwcLabel.Enable = 'off';
                app.hFOVwcEditField.Enable = 'off';
                app.vFOVwcLabel.Enable = 'off';
                app.vFOVwcEditField.Enable = 'off';
                app.omegawcNoteLabel.Visible = 'on';
                app.asteriskLabel.Visible = 'on';
                app.footnoteLabel.Visible = 'on';
                if app.WindCorrButtonGroup.SelectedObject.Text == "Wind correction from ERA5 hourly data on pressure levels"
                    app.timeLabel.Enable = 'off';
                    app.timeEditField.Enable = 'off';
                    app.dateLabel.Enable = 'off';
                    app.dateEditField.Enable = 'off';
                    app.VentHLabel.Enable = 'off';
                    app.VentHEditField.Enable = 'off';
                    app.VentLongLabel.Enable = 'off';
                    app.VentLongEditField.Enable = 'off';
                    app.VentLatLabel.Enable = 'off';
                    app.VentLatEditField.Enable = 'off';
                    app.VentCooLabel.Enable = 'off';
                else
                    app.WindEditField.Enable = 'off';
                    assignin('base','par_wind',NaN)
                end
                app.timeNoteLabel.Visible = "on";
                app.dateNoteLabel.Visible = "on";
                app.VentHNoteLabel.Visible = "on";
                app.VentLatLongNoteLabel.Visible = "on";
                app.WindNoteLabel.Visible = "on";
            end
        end

        % Value changed function: MinDistanceEditField
        function MinDistanceEditFieldValueChanged(app, event)
            assignin('base','par_min_dist',str2double(app.MinDistanceEditField.Value))
        end

        % Value changed function: MaxDistanceEditField
        function MaxDistanceEditFieldValueChanged(app, event)
            assignin('base','par_max_dist',str2double(app.MaxDistanceEditField.Value))
        end

        % Value changed function: hFOVEditField
        function hFOVEditFieldValueChanged(app, event)
            assignin('base','par_beta_h',str2double(app.hFOVEditField.Value))
        end

        % Value changed function: vFOVEditField
        function vFOVEditFieldValueChanged(app, event)
            assignin('base','par_beta_v',str2double(app.vFOVEditField.Value))
        end

        % Value changed function: phiEditField
        function phiEditFieldValueChanged(app, event)
            assignin('base','par_phi',str2double(app.phiEditField.Value))
        end

        % Button pushed function: GeomFileBrowseButton
        function GeomFileBrowseButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt;*.csv'},'Select input file for geometrical calibration',app.InFolderEditField.Value);
            app.PlumeTraP210UIFigure.Visible = 'off';
            app.PlumeTraP210UIFigure.Visible = 'on';
            GeomFile = fullfile(path,file);
            assignin('base','GeometricalData',GeomFile)
            if ischar(GeomFile) || isstring(GeomFile)
                app.GeomFileEditField.Value = GeomFile;
            end
        end

        % Value changed function: GeomFileEditField
        function GeomFileEditFieldValueChanged(app, event)
            assignin('base','GeometricalData',app.GeomFileEditField.Value)
        end

        % Button pushed function: GeomCalHBrowseButton
        function GeomCalHBrowseButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt';'*.csv'},'Select horizontal calibrated data file',app.InFolderEditField.Value);
            app.PlumeTraP210UIFigure.Visible = 'off';
            app.PlumeTraP210UIFigure.Visible = 'on';
            HCalFile = fullfile(path,file);
            assignin('base','HorizontalCalibratedData',HCalFile)
            if ischar(HCalFile) || isstring(HCalFile)
                app.GeomCalHEditField.Value = HCalFile;
            end
        end

        % Value changed function: GeomCalHEditField
        function GeomCalHEditFieldValueChanged(app, event)
            assignin('base','HorizontalCalibratedData',app.GeomCalHEditField.Value)
        end

        % Button pushed function: GeomCalVBrowseButton
        function GeomCalVBrowseButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt';'*.csv'},'Select vertical calibrated data file',app.InFolderEditField.Value);
            app.PlumeTraP210UIFigure.Visible = 'off';
            app.PlumeTraP210UIFigure.Visible = 'on';
            VCalFile = fullfile(path,file);
            assignin('base','VerticalCalibratedData',VCalFile)
            if ischar(VCalFile) || isstring(VCalFile)
                app.GeomCalVEditField.Value = VCalFile;
            end
        end

        % Value changed function: GeomCalVEditField
        function GeomCalVEditFieldValueChanged(app, event)
            assignin('base','VerticalCalibratedData',app.GeomCalVEditField.Value)
        end

        % Value changed function: WindCorrSwitch
        function WindCorrSwitchValueChanged(app, event)
            if app.WindCorrSwitch.Value == "On"
                app.WindCorrectionPanel.Enable = 'on';
                assignin('base','wcor',true)
            elseif app.WindCorrSwitch.Value == "Off"
                app.WindCorrectionPanel.Enable = 'off';
                assignin('base','wcor',false)
            end
        end

        % Value changed function: hFOVwcEditField
        function hFOVwcEditFieldValueChanged(app, event)
            assignin('base','par_beta_h',str2double(app.hFOVwcEditField.Value))
        end

        % Value changed function: vFOVwcEditField
        function vFOVwcEditFieldValueChanged(app, event)
            assignin('base','par_beta_v',str2double(app.vFOVwcEditField.Value))
        end

        % Value changed function: phiwcEditField
        function phiwcEditFieldValueChanged(app, event)
            assignin('base','par_phi',str2double(app.phiwcEditField.Value))
        end

        % Value changed function: omegawcEditField
        function omegawcEditFieldValueChanged(app, event)
            assignin('base','par_omega',str2double(app.omegawcEditField.Value))
        end

        % Selection changed function: WindCorrButtonGroup
        function WindCorrButtonGroupSelectionChanged(app, event)
            if app.WindCorrButtonGroup.SelectedObject.Text == "Wind correction from ERA5 hourly data on pressure levels"
                assignin('base','geopot_nc',app.GeopotNCEditField.Value)
                assignin('base','wind_nc',app.WindNCEditField.Value)
                assignin('base','par_wind',NaN)
                app.WindEditField.Enable = 'off';
                if app.GeomCalSwitch.Value == "Off" || app.GeomCalButtonGroup.SelectedObject.Text == "Min-Max distance from camera to image plane [m]:                    -"
                    app.timeNoteLabel.Visible = 'off';
                    app.dateNoteLabel.Visible = 'off';
                    app.timeLabel.Enable = 'on';
                    app.timeEditField.Enable = 'on';
                    app.dateLabel.Enable = 'on';
                    app.dateEditField.Enable = 'on';
                    app.VentHLabel.Enable = 'on';
                    app.VentHEditField.Enable = 'on';
                    app.VentLongLabel.Enable = 'on';
                    app.VentLongEditField.Enable = 'on';
                    app.VentLatLabel.Enable = 'on';
                    app.VentLatEditField.Enable = 'on';
                    app.VentCooLabel.Enable = 'on';
                    app.VentHNoteLabel.Visible = "off";
                    app.VentLatLongNoteLabel.Visible = "off";
                    app.WindNoteLabel.Visible = "off";
                else
                    app.timeNoteLabel.Visible = "on";
                    app.dateNoteLabel.Visible = "on";
                    app.VentHNoteLabel.Visible = "on";
                    app.VentLatLongNoteLabel.Visible = "on";
                    app.WindNoteLabel.Visible = "on";
                end
                app.WindNCEditField.Enable = 'on';
                app.WindNCBrowseButton.Enable = 'on';
                app.GeopotNCEditField.Enable = 'on';
                app.GeopotNCBrowseButton.Enable = 'on';
            elseif app.WindCorrButtonGroup.SelectedObject.Text == "Wind direction [°]:"
                assignin('base','geopot_nc',NaN)
                assignin('base','wind_nc',NaN)
                if app.GeomCalSwitch.Value == "Off" || app.GeomCalButtonGroup.SelectedObject.Text == "Min-Max distance from camera to image plane [m]:                    -"
                    app.WindEditField.Enable = 'on';
                    assignin('base','par_wind',app.WindEditField.Value)

                    app.timeNoteLabel.Visible = 'off';
                    app.dateNoteLabel.Visible = 'off';
                    app.VentHNoteLabel.Visible = "off";
                    app.VentLatLongNoteLabel.Visible = "off";
                    app.WindNoteLabel.Visible = "off";
                else
                    app.WindEditField.Enable = 'off';
                    assignin('base','par_wind',NaN)

                    app.timeNoteLabel.Visible = 'on';
                    app.dateNoteLabel.Visible = 'on';
                    app.VentHNoteLabel.Visible = "on";
                    app.VentLatLongNoteLabel.Visible = "on";
                    app.WindNoteLabel.Visible = "on";
                end
                app.timeLabel.Enable = 'off';
                app.timeEditField.Enable = 'off';
                app.dateLabel.Enable = 'off';
                app.dateEditField.Enable = 'off';
                app.VentHLabel.Enable = 'off';
                app.VentHEditField.Enable = 'off';
                app.VentLongLabel.Enable = 'off';
                app.VentLongEditField.Enable = 'off';
                app.VentLatLabel.Enable = 'off';
                app.VentLatEditField.Enable = 'off';
                app.VentCooLabel.Enable = 'off';
                app.VentHLabel.Enable = 'off';
                app.VentHEditField.Enable = 'off';
                app.VentLongLabel.Enable = 'off';
                app.VentLongEditField.Enable = 'off';
                app.VentLatLabel.Enable = 'off';
                app.VentLatEditField.Enable = 'off';
                app.VentCooLabel.Enable = 'off';
                app.WindNCEditField.Enable = 'off';
                app.WindNCBrowseButton.Enable = 'off';
                app.GeopotNCEditField.Enable = 'off';
                app.GeopotNCBrowseButton.Enable = 'off';
            end
        end

        % Button pushed function: GeopotNCBrowseButton
        function GeopotNCBrowseButtonPushed(app, event)
            [file,path] = uigetfile({'*.nc'},'Select NetCDF file with geopotential field',app.InFolderEditField.Value);
            app.PlumeTraP210UIFigure.Visible = 'off';
            app.PlumeTraP210UIFigure.Visible = 'on';
            GeopotNCfile = fullfile(path,file);
            assignin('base','geopot_nc',GeopotNCfile)
            if ischar(GeopotNCfile) || isstring(GeopotNCfile)
                app.GeopotNCEditField.Value = GeopotNCfile;
            end
        end

        % Value changed function: GeopotNCEditField
        function GeopotNCEditFieldValueChanged(app, event)
            assignin('base','geopot_nc',app.GeopotNCEditField.Value)
        end

        % Button pushed function: WindNCBrowseButton
        function WindNCBrowseButtonPushed(app, event)
            [file,path] = uigetfile({'*.nc'},'Select NetCDF file with wind field',app.InFolderEditField.Value);
            app.PlumeTraP210UIFigure.Visible = 'off';
            app.PlumeTraP210UIFigure.Visible = 'on';
            WindNCfile = fullfile(path,file);
            assignin('base','wind_nc',WindNCfile)
            if ischar(WindNCfile) || isstring(WindNCfile)
                app.WindNCEditField.Value = WindNCfile;
            end
        end

        % Value changed function: WindNCEditField
        function WindNCEditFieldValueChanged(app, event)
            assignin('base','wind_nc',app.WindNCEditField.Value)
        end

        % Value changed function: VentLatEditField
        function VentLatEditFieldValueChanged(app, event)
            assignin('base','par_vent_lat',str2double(app.VentLatEditField.Value))
        end

        % Value changed function: VentLongEditField
        function VentLongEditFieldValueChanged(app, event)
            assignin('base','par_vent_long',str2double(app.VentLongEditField.Value))
        end

        % Value changed function: VentHEditField
        function VentHEditFieldValueChanged(app, event)
            assignin('base','par_vent_h',str2double(app.VentHEditField.Value))
        end

        % Value changed function: dateEditField
        function dateEditFieldValueChanged(app, event)
            assignin('base','par_date',app.dateEditField.Value)
        end

        % Value changed function: timeEditField
        function timeEditFieldValueChanged(app, event)
            assignin('base','par_time',app.timeEditField.Value)
        end

        % Value changed function: WindEditField
        function WindEditFieldValueChanged(app, event)
            assignin('base','par_wind',str2double(app.WindEditField.Value))
        end

        % Button pushed function: OutFolderBrowseButton
        function OutFolderBrowseButtonPushed(app, event)
            outFolder = uigetdir(app.InFolderEditField.Value,'Select Output Folder');
            app.PlumeTraP210UIFigure.Visible = 'off';
            app.PlumeTraP210UIFigure.Visible = 'on';
            assignin('base','outFolder',outFolder)
            if ischar(outFolder) || isstring(outFolder)
                app.OutFolderEditField.Value = outFolder;
            end
        end

        % Value changed function: OutFolderEditField
        function OutFolderEditFieldValueChanged(app, event)
            assignin('base','outFolder',app.OutFolderEditField.Value)
        end

        % Value changed function: OutFormatDropDown
        function OutFormatDropDownValueChanged(app, event)
            assignin('base','outFormat',sprintf('*.%s',app.OutFormatDropDown.Value))
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.InFolderEditField.Value) || isempty(app.OutFolderEditField.Value) ...
                    || (app.GeometricalCalibrationPanel.Enable == "on" && app.GeomFileEditField.Enable == "on" && isempty(app.GeomFileEditField.Value)) ...
                    || (app.GeometricalCalibrationPanel.Enable == "on" && app.MinDistanceEditField.Enable == "on" && isempty(app.MinDistanceEditField.Value)) ...
                    || (app.GeometricalCalibrationPanel.Enable == "on" && app.MaxDistanceEditField.Enable == "on" && isempty(app.MaxDistanceEditField.Value)) ...
                    || (app.GeometricalCalibrationPanel.Enable == "on" && app.hFOVEditField.Enable == "on" && isempty(app.hFOVEditField.Value)) ...
                    || (app.GeometricalCalibrationPanel.Enable == "on" && app.vFOVEditField.Enable == "on" && isempty(app.vFOVEditField.Value)) ...
                    || (app.GeometricalCalibrationPanel.Enable == "on" && app.phiEditField.Enable == "on" && isempty(app.phiEditField.Value)) ...
                    || (app.GeometricalCalibrationPanel.Enable == "on" && app.GeomCalHEditField.Enable == "on" && isempty(app.GeomCalHEditField.Value)) ...
                    || (app.GeometricalCalibrationPanel.Enable == "on" && app.GeomCalVEditField.Enable == "on" && isempty(app.GeomCalVEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.hFOVwcEditField.Enable == "on" && isempty(app.hFOVwcEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.vFOVwcEditField.Enable == "on" && isempty(app.vFOVwcEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.phiwcEditField.Enable == "on" && isempty(app.phiwcEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.omegawcEditField.Enable == "on" && isempty(app.omegawcEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.GeopotNCEditField.Enable == "on" && isempty(app.GeopotNCEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.WindNCEditField.Enable == "on" && isempty(app.WindNCEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.VentLatEditField.Enable == "on" && isempty(app.VentLatEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.VentLongEditField.Enable == "on" && isempty(app.VentLongEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.VentHEditField.Enable == "on" && isempty(app.VentHEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.dateEditField.Enable == "on" && isempty(app.dateEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.timeEditField.Enable == "on" && isempty(app.timeEditField.Value)) ...
                    || (app.WindCorrectionPanel.Enable == "on" && app.WindEditField.Enable == "on" && isempty(app.WindEditField.Value))
                uiwait(msgbox("Fill all active fields","Warning","warn"))
            elseif app.SourceSwitch.Value == "Videos" ...
                    && (app.GeomCalButtonGroup.SelectedObject.Text == "Min-Max distance from camera to image plane [m]:                    -" ...
                    || (app.GeomCalSwitch.Value == "Off" && app.WindCorrSwitch.Value == "On")) ...
                    && length(evalin('base','files'))>1 && app.CalculateParametersCheckBox.Value == true
                uiwait(msgbox("Multiple videos selection is only possible using a file as input for the geometrical calibration. Select only one video.","Warning","warn"))
            else
                evalin('base', 'PlumeTraP_main')
                delete(app)
            end
        end

        % Close request function: PlumeTraP210UIFigure
        function PlumeTraP210UIFigureCloseRequest(app, event)
            evalin('base', 'clearvars *')
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlumeTraP210UIFigure = uifigure('Visible', 'off');
            app.PlumeTraP210UIFigure.Name = 'PlumeTraP 2.1.0';
            app.PlumeTraP210UIFigure.Icon = 'logoPlumeTraP.gif';
            app.PlumeTraP210UIFigure.CloseRequestFcn = createCallbackFcn(app, @PlumeTraP210UIFigureCloseRequest, true);
            app.RunButton = uibutton(app.PlumeTraP210UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UserGuideLink = uihyperlink(app.PlumeTraP210UIFigure);
            app.UserGuideLink.URL = 'https://github.com/r-simionato/PlumeTraP/blob/4902a3b83067fc8754f42c28e1831d052015453a/PlumeTraP_UserManual.pdf';
            app.UserGuideLink.Text = 'User Guide';
            app.InputPanel = uipanel(app.PlumeTraP210UIFigure);
            app.InputPanel.Title = 'Source Panel';
            app.SourceSwitch = uiswitch(app.InputPanel, 'slider');
            app.SourceSwitch.Items = {'Videos', 'Images'};
            app.SourceSwitch.ValueChangedFcn = createCallbackFcn(app, @SourceSwitchValueChanged, true);
            app.SourceSwitch.Value = 'Videos';
            app.InputVidFormEditField = uieditfield(app.InputPanel, 'text');
            app.InputVidFormEditField.Visible = 'off';
            app.VidFormatDropDown = uidropdown(app.InputPanel);
            app.VidFormatDropDown.Items = {'mp4', 'avi', 'mov', 'mj2', 'm4v', 'mpg', 'other'};
            app.VidFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @VidFormatDropDownValueChanged, true);
            app.VidFormatDropDown.Value = 'mp4';
            app.InputImgFormEditField = uieditfield(app.InputPanel, 'text');
            app.InputImgFormEditField.Visible = 'off';
            app.ImgFormatDropDown = uidropdown(app.InputPanel);
            app.ImgFormatDropDown.Items = {'png', 'jpeg', 'bmp', 'tiff', 'gif', 'other'};
            app.ImgFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @ImgFormatDropDownValueChanged, true);
            app.ImgFormatDropDown.Visible = 'off';
            app.ImgFormatDropDown.Value = 'png';
            app.FormatDropDownLabel = uilabel(app.InputPanel);
            app.FormatDropDownLabel.Text = 'Format:';
            app.InFolderEditField = uieditfield(app.InputPanel, 'text');
            app.InFolderEditField.Editable = 'off';
            app.InFolderEditFieldLabel = uilabel(app.InputPanel);
            app.InFolderEditFieldLabel.Text = 'Input folder (select multiple videos / all the images)';
            app.InFolderBrowseButton = uibutton(app.InputPanel, 'push');
            app.InFolderBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @InFolderBrowseButtonPushed, true);
            app.InFolderBrowseButton.Text = 'Browse';
            app.ProcessingOptionsPanel = uipanel(app.PlumeTraP210UIFigure);
            app.ProcessingOptionsPanel.Title = 'Processing Options';
            app.TimestepbetweenframesfpsLabel = uilabel(app.ProcessingOptionsPanel);
            app.TimestepbetweenframesfpsLabel.Text = 'Timestep:            s';
            app.SaveVideoFramesCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.SaveVideoFramesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveVideoFramesCheckBoxValueChanged, true);
            app.SaveVideoFramesCheckBox.Text = 'Save Video Frames';
            app.SaveVideoFramesCheckBox.Value = true;
            app.ApplyImageProcessingCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.ApplyImageProcessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyImageProcessingCheckBoxValueChanged, true);
            app.ApplyImageProcessingCheckBox.Text = 'Apply Image Processing';
            app.ApplyImageProcessingCheckBox.Value = true;
            app.CalculateParametersCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.CalculateParametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateParametersCheckBoxValueChanged, true);
            app.CalculateParametersCheckBox.Text = 'Calculate Parameters';
            app.CalculateParametersCheckBox.Value = true;
            app.timestep = uieditfield(app.ProcessingOptionsPanel, 'text');
            app.timestep.ValueChangedFcn = createCallbackFcn(app, @timestepValueChanged, true);
            app.timestep.Value = '1';
            app.GeometricalCalibrationPanel = uipanel(app.PlumeTraP210UIFigure);
            app.GeometricalCalibrationPanel.Title = 'Geometrical Calibration';
            app.GeomCalButtonGroup = uibuttongroup(app.GeometricalCalibrationPanel);
            app.GeomCalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GeomCalButtonGroupSelectionChanged, true);
            app.GeomCalManualButton = uiradiobutton(app.GeomCalButtonGroup);
            app.GeomCalManualButton.Text = 'Min-Max distance from camera to image plane [m]:                    -';
            app.GeomCalManualButton.Value = true;
            app.MinDistanceEditField = uieditfield(app.GeomCalButtonGroup, 'text');
            app.MinDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MinDistanceEditFieldValueChanged, true);
            app.MaxDistanceEditField = uieditfield(app.GeomCalButtonGroup, 'text');
            app.MaxDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MaxDistanceEditFieldValueChanged, true);
            app.FOVLabel = uilabel(app.GeomCalButtonGroup);
            app.FOVLabel.Text = 'Horizontal-Vertical FOV [°]:                                                          -';
            app.hFOVEditField = uieditfield(app.GeomCalButtonGroup, 'text');
            app.hFOVEditField.ValueChangedFcn = createCallbackFcn(app, @hFOVEditFieldValueChanged, true);
            app.vFOVEditField = uieditfield(app.GeomCalButtonGroup, 'text');
            app.vFOVEditField.ValueChangedFcn = createCallbackFcn(app, @vFOVEditFieldValueChanged, true);
            app.phiEditField = uieditfield(app.GeomCalButtonGroup, 'text');
            app.phiEditField.ValueChangedFcn = createCallbackFcn(app, @phiEditFieldValueChanged, true);
            app.phiLabel = uilabel(app.GeomCalButtonGroup);
            app.phiLabel.Text = 'Camera inclination [°]:';
            app.GeomCalFileButton = uiradiobutton(app.GeomCalButtonGroup);
            app.GeomCalFileButton.Text = 'File (txt or csv)';
            app.GeomFileEditField = uieditfield(app.GeomCalButtonGroup, 'text');
            app.GeomFileEditField.ValueChangedFcn = createCallbackFcn(app, @GeomFileEditFieldValueChanged, true);
            app.GeomFileEditField.Enable = 'off';
            app.GeomFileEditField.Placeholder = 'See User Guide for file format';
            app.GeomFileBrowseButton = uibutton(app.GeomCalButtonGroup, 'push');
            app.GeomFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @GeomFileBrowseButtonPushed, true);
            app.GeomFileBrowseButton.Enable = 'off';
            app.GeomFileBrowseButton.Text = 'Browse';
            app.GeomCalHEditField = uieditfield(app.GeometricalCalibrationPanel, 'text');
            app.GeomCalHEditField.ValueChangedFcn = createCallbackFcn(app, @GeomCalHEditFieldValueChanged, true);
            app.GeomCalHEditField.Enable = 'off';
            app.GeomCalHEditField.Visible = 'off';
            app.GeomCalHEditField.Placeholder = 'See User Guide for file format';
            app.GeomCalHBrowseButton = uibutton(app.GeometricalCalibrationPanel, 'push');
            app.GeomCalHBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @GeomCalHBrowseButtonPushed, true);
            app.GeomCalHBrowseButton.Enable = 'off';
            app.GeomCalHBrowseButton.Visible = 'off';
            app.GeomCalHBrowseButton.Text = 'Browse';
            app.HorizontalEditFieldLabel = uilabel(app.GeometricalCalibrationPanel);
            app.HorizontalEditFieldLabel.Enable = 'off';
            app.HorizontalEditFieldLabel.Visible = 'off';
            app.HorizontalEditFieldLabel.Text = 'Horizontal';
            app.GeomCalVEditField = uieditfield(app.GeometricalCalibrationPanel, 'text');
            app.GeomCalVEditField.ValueChangedFcn = createCallbackFcn(app, @GeomCalVEditFieldValueChanged, true);
            app.GeomCalVEditField.Enable = 'off';
            app.GeomCalVEditField.Visible = 'off';
            app.GeomCalVEditField.Placeholder = 'See User Guide for file format';
            app.GeomCalVBrowseButton = uibutton(app.GeometricalCalibrationPanel, 'push');
            app.GeomCalVBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @GeomCalVBrowseButtonPushed, true);
            app.GeomCalVBrowseButton.Enable = 'off';
            app.GeomCalVBrowseButton.Visible = 'off';
            app.GeomCalVBrowseButton.Text = 'Browse';
            app.VerticalEditFieldLabel = uilabel(app.GeometricalCalibrationPanel);
            app.VerticalEditFieldLabel.Enable = 'off';
            app.VerticalEditFieldLabel.Visible = 'off';
            app.VerticalEditFieldLabel.Text = 'Vertical';
            app.GeomCalLabel = uilabel(app.GeometricalCalibrationPanel);
            app.GeomCalLabel.Enable = 'off';
            app.GeomCalLabel.Visible = 'off';
            app.GeomCalLabel.Text = 'Calibrated data files path (txt or csv) - may include wind correction';
            app.WindCorrectionPanel = uipanel(app.PlumeTraP210UIFigure);
            app.WindCorrectionPanel.Title = 'Wind Correction';
            app.omegawcLabel = uilabel(app.WindCorrectionPanel);
            app.omegawcLabel.Text = 'Camera orientation [°]:';
            app.omegawcEditField = uieditfield(app.WindCorrectionPanel, 'text');
            app.omegawcEditField.ValueChangedFcn = createCallbackFcn(app, @omegawcEditFieldValueChanged, true);
            app.phiwcLabel = uilabel(app.WindCorrectionPanel);
            app.phiwcLabel.Enable = 'off';
            app.phiwcLabel.Text = 'Camera inclination [°]:';
            app.phiwcEditField = uieditfield(app.WindCorrectionPanel, 'text');
            app.phiwcEditField.ValueChangedFcn = createCallbackFcn(app, @phiwcEditFieldValueChanged, true);
            app.phiwcEditField.Enable = 'off';
            app.hFOVwcLabel = uilabel(app.WindCorrectionPanel);
            app.hFOVwcLabel.Enable = 'off';
            app.hFOVwcLabel.Text = 'Horizontal FOV [°]:';
            app.hFOVwcEditField = uieditfield(app.WindCorrectionPanel, 'text');
            app.hFOVwcEditField.ValueChangedFcn = createCallbackFcn(app, @hFOVwcEditFieldValueChanged, true);
            app.hFOVwcEditField.Enable = 'off';
            app.vFOVwcLabel = uilabel(app.WindCorrectionPanel);
            app.vFOVwcLabel.Enable = 'off';
            app.vFOVwcLabel.Text = 'Vertical FOV [°]:';
            app.vFOVwcEditField = uieditfield(app.WindCorrectionPanel, 'text');
            app.vFOVwcEditField.ValueChangedFcn = createCallbackFcn(app, @vFOVwcEditFieldValueChanged, true);
            app.vFOVwcEditField.Enable = 'off';
            app.WindCorrButtonGroup = uibuttongroup(app.WindCorrectionPanel);
            app.WindCorrButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindCorrButtonGroupSelectionChanged, true);
            app.WindNoteLabel = uilabel(app.WindCorrButtonGroup);
            app.WindNoteLabel.Visible = 'off';
            app.WindNoteLabel.Text = '*';
            app.timeNoteLabel = uilabel(app.WindCorrButtonGroup);
            app.timeNoteLabel.Visible = 'off';
            app.timeNoteLabel.Text = '*';
            app.dateNoteLabel = uilabel(app.WindCorrButtonGroup);
            app.dateNoteLabel.Visible = 'off';
            app.dateNoteLabel.Text = '*';
            app.VentHNoteLabel = uilabel(app.WindCorrButtonGroup);
            app.VentHNoteLabel.Visible = 'off';
            app.VentHNoteLabel.Text = '*';
            app.VentLatLongNoteLabel = uilabel(app.WindCorrButtonGroup);
            app.VentLatLongNoteLabel.Visible = 'off';
            app.VentLatLongNoteLabel.Text = '*';
            app.ERA5Button = uiradiobutton(app.WindCorrButtonGroup);
            app.ERA5Button.Text = 'Wind correction from ERA5 hourly data on pressure levels';
            app.ERA5Button.Value = true;
            app.GeopotNCBrowseButton = uibutton(app.WindCorrButtonGroup, 'push');
            app.GeopotNCBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @GeopotNCBrowseButtonPushed, true);
            app.GeopotNCBrowseButton.Text = 'Browse geopot.nc';
            app.GeopotNCEditField = uieditfield(app.WindCorrButtonGroup, 'text');
            app.GeopotNCEditField.ValueChangedFcn = createCallbackFcn(app, @GeopotNCEditFieldValueChanged, true);
            app.WindNCBrowseButton = uibutton(app.WindCorrButtonGroup, 'push');
            app.WindNCBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @WindNCBrowseButtonPushed, true);
            app.WindNCBrowseButton.Text = 'Browse wind.nc';
            app.WindNCEditField = uieditfield(app.WindCorrButtonGroup, 'text');
            app.WindNCEditField.ValueChangedFcn = createCallbackFcn(app, @WindNCEditFieldValueChanged, true);
            app.VentLatLabel = uilabel(app.WindCorrButtonGroup);
            app.VentLatLabel.Text = 'Lat.';
            app.VentLatEditField = uieditfield(app.WindCorrButtonGroup, 'text');
            app.VentLatEditField.ValueChangedFcn = createCallbackFcn(app, @VentLatEditFieldValueChanged, true);
            app.VentLatEditField.Placeholder = '(-)DD.dd';
            app.VentLongLabel = uilabel(app.WindCorrButtonGroup);
            app.VentLongLabel.Text = 'Long.';
            app.VentLongEditField = uieditfield(app.WindCorrButtonGroup, 'text');
            app.VentLongEditField.ValueChangedFcn = createCallbackFcn(app, @VentLongEditFieldValueChanged, true);
            app.VentLongEditField.Placeholder = '(-)DD.dd';
            app.VentCooLabel = uilabel(app.WindCorrButtonGroup);
            app.VentCooLabel.Tooltip = {''};
            app.VentCooLabel.Text = 'Vent coordinates [DD (0.25 step)]:';
            app.VentHLabel = uilabel(app.WindCorrButtonGroup);
            app.VentHLabel.Text = 'Vent height [m asl]:';
            app.VentHEditField = uieditfield(app.WindCorrButtonGroup, 'text');
            app.VentHEditField.ValueChangedFcn = createCallbackFcn(app, @VentHEditFieldValueChanged, true);
            app.dateLabel = uilabel(app.WindCorrButtonGroup);
            app.dateLabel.Text = 'Date [dd-Mmm-yyyy]:';
            app.dateEditField = uieditfield(app.WindCorrButtonGroup, 'text');
            app.dateEditField.ValueChangedFcn = createCallbackFcn(app, @dateEditFieldValueChanged, true);
            app.dateEditField.Placeholder = '29-Jun-1997';
            app.timeLabel = uilabel(app.WindCorrButtonGroup);
            app.timeLabel.Text = 'Time [hh:00:00]:';
            app.timeEditField = uieditfield(app.WindCorrButtonGroup, 'text');
            app.timeEditField.ValueChangedFcn = createCallbackFcn(app, @timeEditFieldValueChanged, true);
            app.timeEditField.Placeholder = 'hh:00:00';
            app.knownWindManualButton = uiradiobutton(app.WindCorrButtonGroup);
            app.knownWindManualButton.Text = 'Wind direction [°]:';
            app.WindEditField = uieditfield(app.WindCorrButtonGroup, 'text');
            app.WindEditField.ValueChangedFcn = createCallbackFcn(app, @WindEditFieldValueChanged, true);
            app.WindEditField.Enable = 'off';
            app.WindEditField.Tooltip = {'direction from which the wind originates'};
            app.omegawcNoteLabel = uilabel(app.WindCorrectionPanel);
            app.omegawcNoteLabel.Visible = 'off';
            app.omegawcNoteLabel.Text = '*';
            app.asteriskLabel = uilabel(app.WindCorrectionPanel);
            app.asteriskLabel.Visible = 'off';
            app.asteriskLabel.Text = '*';
            app.footnoteLabel = uilabel(app.WindCorrectionPanel);
            app.footnoteLabel.Visible = 'off';
            app.footnoteLabel.Text = 'to be included in the geometrical calibration file';
            app.OutFolderPanel = uipanel(app.PlumeTraP210UIFigure);
            app.OutFolderPanel.Title = 'Output Folder';
            app.OutFolderEditField = uieditfield(app.OutFolderPanel, 'text');
            app.OutFolderEditField.ValueChangedFcn = createCallbackFcn(app, @OutFolderEditFieldValueChanged, true);
            app.OutFolderBrowseButton = uibutton(app.OutFolderPanel, 'push');
            app.OutFolderBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @OutFolderBrowseButtonPushed, true);
            app.OutFolderBrowseButton.Text = 'Browse';
            app.OutFormatLabel = uilabel(app.OutFolderPanel);
            app.OutFormatLabel.Text = 'Format:';
            app.OutFormatDropDown = uidropdown(app.OutFolderPanel);
            app.OutFormatDropDown.Items = {'png', 'jpeg', 'bmp', 'tiff', 'gif'};
            app.OutFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @OutFormatDropDownValueChanged, true);
            app.OutFormatDropDown.Value = 'png';
            app.WindCorrSwitch = uiswitch(app.PlumeTraP210UIFigure, 'slider');
            app.WindCorrSwitch.Items = {'On', 'Off'};
            app.WindCorrSwitch.ValueChangedFcn = createCallbackFcn(app, @WindCorrSwitchValueChanged, true);
            app.WindCorrSwitch.Value = 'On';
            app.GeomCalSwitch = uiswitch(app.PlumeTraP210UIFigure, 'slider');
            app.GeomCalSwitch.Items = {'On', 'Off'};
            app.GeomCalSwitch.ValueChangedFcn = createCallbackFcn(app, @GeomCalSwitchValueChanged, true);
            app.GeomCalSwitch.Value = 'On';
            app.CopyrightLabel = uilabel(app.PlumeTraP210UIFigure);
            app.CopyrightLabel.Text = 'Copyright (C) 2024 Riccardo Simionato';
            app.Hyperlink = uihyperlink(app.PlumeTraP210UIFigure);
            app.Hyperlink.URL = 'https://www.gnu.org/licenses/gpl-3.0.html';
            app.Hyperlink.Text = 'GNU General Public License ';
            % Show the figure after all components are created
            app.PlumeTraP210UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlumeTraP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlumeTraP210UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlumeTraP210UIFigure)
        end
    end
end